classdef one_cycle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OnecycleUIFigure              matlab.ui.Figure
        NbsidesEditField              matlab.ui.control.NumericEditField
        NbsidesEditFieldLabel         matlab.ui.control.Label
        typeOfSelection               matlab.ui.control.DropDown
        DropDownLabel                 matlab.ui.control.Label
        Label                         matlab.ui.control.TextArea
        PulsewavesectioningCheckBox   matlab.ui.control.CheckBox
        PulsewaveanalysisCheckBox     matlab.ui.control.CheckBox
        ReferenceDirectory            matlab.ui.control.TextArea
        FilesDirectory                matlab.ui.control.TextArea
        ErrorLabel                    matlab.ui.control.Label
        OutputLength                  matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        ApplytoreferencetooCheckBox   matlab.ui.control.CheckBox
        ClearButton                   matlab.ui.control.Button
        LoadreferenceButton           matlab.ui.control.Button
        ExecuteButton                 matlab.ui.control.Button
        NumberofframesEditField       matlab.ui.control.NumericEditField
        NumberofframesEditFieldLabel  matlab.ui.control.Label
        LoadfilesButton               matlab.ui.control.Button
    end

    properties (Access = private)
        files = {}
        reference
        refvideosize
        plot_printed = false
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadfilesButton
        function LoadfilesButtonPushed(app, event)
            
            [file,path] = uigetfile(strcat(app.reference.directory,'*.avi;*.raw'),'Select files','MultiSelect','on');
            if ~isfloat(file)
                if ~iscell(file)
                    file  = {file};
                end
                if length(file)~= app.reference.nbFiles
                    app.ErrorLabel.Text = "Error: Number of files not correct" ; 
                    return ; 
                end
                app.LoadfilesButton.Enable = false ; 
                app.Lamp.Color = [1, 0, 0];
                drawnow;
                app.files{end + 1} = OneCycleClass(file,path,app.refvideosize);
                app.OutputLength.Text = num2str(length(app.files)) ;
                if strcmp(app.FilesDirectory.Value, '')
                    app.FilesDirectory.Value = path ;
                else
                    app.FilesDirectory.Value{end + 1} = path;
                end
        end
            app.ErrorLabel.Text = "" ; 
            app.LoadfilesButton.Enable = true ;
            app.Lamp.Color = [0, 1, 0] ;
        end

        % Button pushed function: LoadreferenceButton
        function LoadreferenceButtonPushed(app, event)
            if (isempty(app.reference))
                [file,path] = uigetfile('*.avi;*.raw','Select reference files','MultiSelect','on');
            else
                [file,path] = uigetfile(strcat(app.reference.directory, '*.avi;*.raw'),'Select reference files','MultiSelect','on');
            end
            if ~isfloat(file)
                for n = 1:length(app.files)
                    if length(file)~=app.files{n}.nbFiles 
                        app.ErrorLabel.Text = "Error: number of files not correct";
                        return ; 
                    end 
                end
                app.Lamp.Color = [1, 0, 0];
                drawnow;
                if ~iscell(file)
                    file = {file};
                end
                file
                path
                app.reference = OneCycleClass(file,path);
                app.LoadfilesButton.Enable = true ;
                app.ExecuteButton.Enable = true ;
                app.ClearButton.Enable = true ;
                % FIXME app.ReferenceDirectory.Value = fullfile(path,file)
                app.ReferenceDirectory.Value = path ;
                app.refvideosize = size(app.reference.data{1});
            end
            app.ErrorLabel.Text = "" ;
            app.Lamp.Color = [0, 1, 0];
            app.Label.Value = ' ';
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
           
%             delete(gcp('nocreate')); % closeparallel CPU pool
%             parpool;% launch parallel CPU pool
            
            app.Lamp.Color = [1, 0, 0];
            app.ErrorLabel.Text = "" ;
            drawnow;
%             app.reference = app.reference.MomentNormalize();
tic
            for n = 1:length(app.files)
                app.files{n} = app.files{n}.MomentNormalize();
            end
toc
            % Interp2 videos
            for n = 1:length(app.files)
                for jj = 1:size(app.files{n},3)
                    app.files{n}.data{1}(:,:,jj) = interp2(app.files{n}.data{1}(:,:,jj), 2);
                    app.files{n}.dataM1M0{1}(:,:,jj) = interp2(app.files{n}.dataM1M0{1}(:,:,jj), 2);
                end
            end
            for jj = 1:size(app.reference,3)
                    app.reference.data{1}(:,:,jj) = interp2(app.reference.data{1}(:,:,jj), 2);
            end

            [sys_index_list_cell, mask_cell, maskArtery2, fullPulseWave_cell] = app.reference.getSystole();

            
            if app.ApplytoreferencetooCheckBox.Value 
                app.reference.onePulse(fullPulseWave_cell, mask_cell, maskArtery2, sys_index_list_cell,app.NumberofframesEditField.Value, app.PulsewaveanalysisCheckBox.Value) ; 
            end
            for n = 1:length(app.files)
                app.files{n}.onePulse(fullPulseWave_cell,mask_cell, maskArtery2, sys_index_list_cell, app.NumberofframesEditField.Value, app.PulsewaveanalysisCheckBox.Value);
            end
            app.Lamp.Color = [0, 1, 0];
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.files = {};
            app.reference = [];
            app.OutputLength.Text = num2str(0) ;
            app.FilesDirectory.Value = "";
            app.ReferenceDirectory.Value = "";
            app.LoadreferenceButton.Enable = true;
            app.LoadfilesButton.Enable = true;
        end

        % Value changed function: PulsewavesectioningCheckBox
        function PulsewavesectioningCheckBoxValueChanged(app, event)
%             value = app.PulsewavesectioningCheckBox.Value;
%             if value
%                 if isempty(app.reference)
%                     app.Label.Value = 'Select a *.avi* file before Sectioning';
%                 else
%                     app.plot_printed = section_plot(app.reference.video_loaded, app.reference.pictureSection, app.reference.videoSection, app.Label, maskArtery, maskArteryInPlane, v_RMS);
%             
%                 end
%             end
%             %
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OnecycleUIFigure = uifigure('Visible', 'off');
            app.OnecycleUIFigure.Name = 'One cycle';
            app.LoadfilesButton = uibutton(app.OnecycleUIFigure, 'push');
            app.LoadfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfilesButtonPushed, true);
            app.LoadfilesButton.Enable = 'off';
            app.LoadfilesButton.Text = 'Load files';
            app.NumberofframesEditFieldLabel = uilabel(app.OnecycleUIFigure);
            app.NumberofframesEditFieldLabel.Text = 'Number of frames ';
            app.NumberofframesEditField = uieditfield(app.OnecycleUIFigure, 'numeric');
            app.NumberofframesEditField.Limits = [1 Inf];
            app.NumberofframesEditField.Value = 256;
            app.ExecuteButton = uibutton(app.OnecycleUIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Enable = 'off';
            app.ExecuteButton.Text = 'Execute';
            app.LoadreferenceButton = uibutton(app.OnecycleUIFigure, 'push');
            app.LoadreferenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadreferenceButtonPushed, true);
            app.LoadreferenceButton.Text = 'Load reference';
            app.ClearButton = uibutton(app.OnecycleUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Enable = 'off';
            app.ClearButton.Text = 'Clear';
            app.ApplytoreferencetooCheckBox = uicheckbox(app.OnecycleUIFigure);
            app.ApplytoreferencetooCheckBox.Text = 'Apply to reference too';
            app.Lamp = uilamp(app.OnecycleUIFigure);
            app.OutputLength = uilabel(app.OnecycleUIFigure);
            app.OutputLength.Text = '0';
            app.ErrorLabel = uilabel(app.OnecycleUIFigure);
            app.ErrorLabel.Text = '';
            app.FilesDirectory = uitextarea(app.OnecycleUIFigure);
            app.FilesDirectory.Editable = 'off';
            app.ReferenceDirectory = uitextarea(app.OnecycleUIFigure);
            app.PulsewaveanalysisCheckBox = uicheckbox(app.OnecycleUIFigure);
            app.PulsewaveanalysisCheckBox.Text = 'Pulse wave analysis';
            app.PulsewaveanalysisCheckBox.Value = true;
            app.PulsewavesectioningCheckBox = uicheckbox(app.OnecycleUIFigure);
            app.PulsewavesectioningCheckBox.ValueChangedFcn = createCallbackFcn(app, @PulsewavesectioningCheckBoxValueChanged, true);
            app.PulsewavesectioningCheckBox.Text = 'Pulse wave sectioning';
            app.Label = uitextarea(app.OnecycleUIFigure);
            app.DropDownLabel = uilabel(app.OnecycleUIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.typeOfSelection = uidropdown(app.OnecycleUIFigure);
            app.typeOfSelection.Items = {'Automatic', 'Manual'};
            app.typeOfSelection.Value = 'Automatic';
            app.NbsidesEditFieldLabel = uilabel(app.OnecycleUIFigure);
            app.NbsidesEditFieldLabel.Text = 'Nb sides';
            app.NbsidesEditField = uieditfield(app.OnecycleUIFigure, 'numeric');
            app.NbsidesEditField.Limits = [0 120];
            app.NbsidesEditField.RoundFractionalValues = 'on';
            app.NbsidesEditField.Value = 120;
            % Show the figure after all components are created
            app.OnecycleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = one_cycle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OnecycleUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OnecycleUIFigure)
        end
    end
end